classdef environmentDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SaveEditField          matlab.ui.control.EditField
        SaveEditFieldLabel     matlab.ui.control.Label
        Tree                   matlab.ui.container.CheckBoxTree
        ObsticlesNode          matlab.ui.container.TreeNode
        BasicWallNode          matlab.ui.container.TreeNode
        GoalNode               matlab.ui.container.TreeNode
        BasicGoalNode          matlab.ui.container.TreeNode
        RemoveNode             matlab.ui.container.TreeNode
        PointerSpinner_X       matlab.ui.control.Spinner
        FieldSizeSpinner_X     matlab.ui.control.Spinner
        PointerSpinner_Y       matlab.ui.control.Spinner
        PointerSpinnerLabel    matlab.ui.control.Label
        FieldSizeSpinner_Y     matlab.ui.control.Spinner
        FieldSizeSpinnerLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        gridWorld % Description
        
        pointerX
        pointerY
        
        gridDir
        
    end
    
    methods (Access = private)


        function refresh(app)
            env = rlMDPEnv(app.gridWorld);
            plot(env)
            
            [app.pointerX, app.pointerY] = ginput(1);
            
            app.pointerX = round(app.pointerX, 0);
            app.pointerY = round(app.pointerY, 0);
            
            app.PointerSpinner_X.Value = abs(app.pointerX);
            app.PointerSpinner_Y.Value = abs(app.pointerY);
            
            updateField(app)
            
        end
        
        function transferGridWorld(app, oldGridWorld)
            
            app.gridWorld.CurrentState = oldGridWorld.CurrentState;
            
            app.gridWorld.ObstacleStates = oldGridWorld.ObstacleStates;
            app.gridWorld.TerminalStates = oldGridWorld.TerminalStates;
        end
        
        function updateField(app)
            choice = app.Tree.CheckedNodes(end).Text;
            
            newValue = "[" + string(app.PointerSpinner_Y.Value) + "," + string(app.PointerSpinner_X.Value) + "]";
            
            switch(choice)
                case("Basic Goal")
                     app.gridWorld.TerminalStates = [app.gridWorld.TerminalStates; newValue];
                case("Basic Wall")
                    app.gridWorld.ObstacleStates = [app.gridWorld.ObstacleStates; newValue];
                otherwise
                    disp("Not Defined")
            end
            
            app.gridWorld.R(:,state2idx(app.gridWorld,app.gridWorld.ObstacleStates),:) = -20;
            app.gridWorld.R(:,state2idx(app.gridWorld,app.gridWorld.TerminalStates),:) = 10;
            
            refresh(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            app.gridWorld = createGridWorld(5,5);
            app.gridDir = "gridWorlds\";
            
            
            refresh(app)


        end

        % Callback function
        function ObsticleButtonPushed(app, event)
            
            newValue = "[" + string(abs(app.PointerSpinner_Y.Value)) + "," + string(abs(app.PointerSpinner_X.Value)) + "]";
            
            app.gridWorld.ObstacleStates = [app.gridWorld.ObstacleStates; newValue];
            
            refresh(app)


        end

        % Value changing function: FieldSizeSpinner_X
        function FieldSizeSpinner_XValueChanging(app, event)
            changingValue = event.Value;
            
            oldGridWorld = app.gridWorld;
            
            app.gridWorld = createGridWorld(changingValue, app.gridWorld.GridSize(1,2));
            
            transferGridWorld(app, oldGridWorld)
            
            refresh(app)
            
        end

        % Value changing function: FieldSizeSpinner_Y
        function FieldSizeSpinner_YValueChanging(app, event)
            changingValue = event.Value;
            
            oldGridWorld = app.gridWorld;
            
            app.gridWorld.GridSize(1,1)
            
            app.gridWorld = createGridWorld(app.gridWorld.GridSize(1,1), changingValue);
            
            transferGridWorld(app, oldGridWorld)
            
            refresh(app)
            
        end

        % Callback function
        function GoalButtonPushed(app, event)
            
            newValue = "[" + string(app.PointerSpinner_Y.Value) + "," + string(app.PointerSpinner_X.Value) + "]";
            
            app.gridWorld.TerminalStates = [app.gridWorld.TerminalStates; newValue];
            
            refresh(app)


        end

        % Callback function
        function RemoveButtonPushed(app, event)
            
            
            newValue = "[" + string(app.PointerSpinner_Y.Value) + "," + string(app.PointerSpinner_X.Value) + "]";
            
            A = app.gridWorld.TerminalStates;
            
            [LocX, locY] = ismember(A, newValue);
            
            temp = sum(LocX) + sum(locY);
            
            if(temp >= 1)
                app.gridWorld.TerminalStates(locY) = [];
                app.gridWorld.TerminalStates = transpose(app.gridWorld.TerminalStates);
            end
            
            A = app.gridWorld.ObstacleStates;
            
            [LocX, locY] = ismember(A, newValue);
            
            temp = sum(LocX) + sum(locY);
            
            if(temp >= 1)
                app.gridWorld.ObstacleStates(locY) = [];
                app.gridWorld.TerminalStates = transpose(app.gridWorld.TerminalStates);
            end
            
            refresh(app)


        end

        % Value changed function: SaveEditField
        function SaveEditFieldValueChanged(app, event)
            value = app.SaveEditField.Value + ".mat";
            
            gridWorld = app.gridWorld;
            
            save(app.gridDir + value, 'gridWorld')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FieldSizeSpinnerLabel = uilabel(app.UIFigure);
            app.FieldSizeSpinnerLabel.Text = 'Field Size';
            app.FieldSizeSpinner_Y = uispinner(app.UIFigure);
            app.FieldSizeSpinner_Y.ValueChangingFcn = createCallbackFcn(app, @FieldSizeSpinner_YValueChanging, true);
            app.FieldSizeSpinner_Y.Limits = [1 Inf];
            app.FieldSizeSpinner_Y.Value = 5;
            app.PointerSpinnerLabel = uilabel(app.UIFigure);
            app.PointerSpinnerLabel.Text = 'Pointer';
            app.PointerSpinner_Y = uispinner(app.UIFigure);
            app.PointerSpinner_Y.Value = 1;
            app.FieldSizeSpinner_X = uispinner(app.UIFigure);
            app.FieldSizeSpinner_X.ValueChangingFcn = createCallbackFcn(app, @FieldSizeSpinner_XValueChanging, true);
            app.FieldSizeSpinner_X.Limits = [1 Inf];
            app.FieldSizeSpinner_X.Value = 5;
            app.PointerSpinner_X = uispinner(app.UIFigure);
            app.PointerSpinner_X.Value = 1;
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.ObsticlesNode = uitreenode(app.Tree);
            app.ObsticlesNode.Text = 'Obsticles';
            app.BasicWallNode = uitreenode(app.ObsticlesNode);
            app.BasicWallNode.Text = 'Basic Wall';
            app.GoalNode = uitreenode(app.Tree);
            app.GoalNode.Text = 'Goal';
            app.BasicGoalNode = uitreenode(app.GoalNode);
            app.BasicGoalNode.Text = 'Basic Goal';
            app.RemoveNode = uitreenode(app.Tree);
            app.RemoveNode.Text = 'Remove';
            app.SaveEditFieldLabel = uilabel(app.UIFigure);
            app.SaveEditFieldLabel.Text = 'Save';
            app.SaveEditField = uieditfield(app.UIFigure, 'text');
            app.SaveEditField.ValueChangedFcn = createCallbackFcn(app, @SaveEditFieldValueChanged, true);
            app.SaveEditField.Value = 'FileName';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = environmentDesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end